classdef image_overlayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageOverlayerUIFigure  matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        nEditField              matlab.ui.control.NumericEditField
        nEditFieldLabel         matlab.ui.control.Label
        OrderDropDown           matlab.ui.control.DropDown
        OrderDropDownLabel      matlab.ui.control.Label
        TransformationDropDown  matlab.ui.control.DropDown
        TransformationLabel     matlab.ui.control.Label
        SavepointsButton        matlab.ui.control.Button
        LoadpointsButton        matlab.ui.control.Button
        CorrelationLabel        matlab.ui.control.Label
        TypeButtonGroup         matlab.ui.container.ButtonGroup
        ColorButton             matlab.ui.control.RadioButton
        BothButton              matlab.ui.control.RadioButton
        BaseButton              matlab.ui.control.RadioButton
        OverlayButton           matlab.ui.control.RadioButton
        CorrelationGauge        matlab.ui.control.Gauge
        CorrelationGaugeLabel   matlab.ui.control.Label
        Lamp                    matlab.ui.control.Lamp
        SaveresultButton        matlab.ui.control.Button
        EditexistingButton      matlab.ui.control.Button
        StartnewButton          matlab.ui.control.Button
        SelectoverlayButton     matlab.ui.control.Button
        Overlay                 matlab.ui.control.Image
        OverlayLabel            matlab.ui.control.Label
        SelectbaseButton        matlab.ui.control.Button
        BaseLabel               matlab.ui.control.Label
        Base                    matlab.ui.control.Image
        Result                  matlab.ui.control.Image
    end

    
    properties (Access = private)
        % Figures.
        base; % Base figure.
        overlay; % Overlay figure.
        overlay_transformed; % Transformed overlay figure.

        % Control points.
        fp; % Fixed (base) control points.
        mp; % Moving (overlay) control points.
        mp_transformed; % Transformed moving (overlay) control points.

        % Settings.
        plot_moving = true; % Whether transformation allows transformed moving control points on plot.
        order = 2;
        n = 1;
    end
    
    methods (Access = private)
        
        function transform(app)
            % Infer geometric transformation.
            switch app.TransformationDropDown.Value
                case 'polynomial'
                    transformation = fitgeotrans(app.mp, app.fp, app.TransformationDropDown.Value, str2double(app.OrderDropDown.Value));
                case 'lwm'
                    transformation = fitgeotrans(app.mp, app.fp, app.TransformationDropDown.Value, app.nEditField.Value);
                otherwise
                    transformation = fitgeotrans(app.mp, app.fp, app.TransformationDropDown.Value);
            end

            % Transform overlay image.
            base_coord = imref2d(size(app.base));
            app.overlay_transformed = imwarp(app.overlay, transformation, 'OutputView', base_coord);
            
            if app.plot_moving
                % Transform moving control pionts.
                app.mp_transformed = transformPointsForward(transformation, app.mp);

                % Update cosine similarity.
                correlation = corr2(app.fp, app.mp_transformed);
                app.CorrelationGauge.Value = correlation;
                app.CorrelationLabel.Text = sprintf('%.3f', round(correlation, 3));
            else
                app.CorrelationGauge.Value = 0;
                app.CorrelationLabel.Text = "N.A.";
            end
        end

        function plot_map(app)
            % Plot result.
            close all
            fig = figure('Visible', 'off');
            ax = axes('Parent', fig);

            % Check which type is selected.
            switch app.TypeButtonGroup.SelectedObject
                case app.ColorButton
                    % Display using default brightness scaling.
                    app.Result.ImageSource = imshowpair(app.base, app.overlay_transformed, 'Parent', ax).CData;
                case app.BaseButton
                    % Display using base.
                    app.Result.ImageSource = imshow(app.base, 'Parent', ax).CData;
                case app.OverlayButton
                    % Display using overlay
                    app.Result.ImageSource = imshow(app.overlay_transformed, 'Parent', ax).CData;
                case app.BothButton
                    % Display using both base and overlay.
                    imshow(app.base, 'Parent', ax);
                    hold(ax, 'on');

                    % Mask transformed overlay to make black edges transparent.
                    mask = double(any(app.overlay_transformed, 3));
                    image(app.overlay_transformed, 'AlphaData', mask, 'Parent', ax);

                    % Plot fixed control points.
                    plot(ax, app.fp(:, 1), app.fp(:, 2), 'b+', 'MarkerSize', 14, 'LineWidth', 2);

                    if app.plot_moving
                        % Plot control point differences.
                        for cp = 1:size(app.fp, 1)
                            plot(ax, [app.fp(cp, 1), app.mp_transformed(cp, 1)], [app.fp(cp, 2), app.mp_transformed(cp, 2)], 'g-', 'LineWidth', 2)
                        end

                        % Plot moving control points.
                        plot(ax, app.mp_transformed(:, 1), app.mp_transformed(:, 2), 'r+', 'MarkerSize', 14, 'LineWidth', 2);
                    end

                    hold(ax, 'off');
                    app.Result.ImageSource = getframe(ax).cdata;
            end
            close(fig);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectbaseButton
        function SelectbaseButtonPushed(app, event)
            % Load base figure.
            close all
            try
                % Allow user to select a file.
                [base_name, base_path] = uigetfile({'*.gif;*.jpg;*.png;*.tif;*.tiff', 'Figures (*.gif,*.jpg,*.png,*.tif,*.tiff)'; '*.*', 'All Files (*.*)'}, "Base image");
                drawnow; pause(0.1);
                figure(app.ImageOverlayerUIFigure);

                if ~isequal(base_name, 0) && ~isequal(base_path, 0)
                    % A file was selected.
                    cd(base_path);

                    % Load image.
                    app.base = imread(string(base_path) + string(base_name));
                    app.Base.ImageSource = app.base;
                    app.Lamp.Color = [1, 0, 0];
                end
            catch
                figure(app.ImageOverlayerUIFigure)
                uialert(app.ImageOverlayerUIFigure, "That didn't work, please try again. Did you select a figure?", "Invalid file");
                drawnow; pause(0.1);
            end
        end

        % Button pushed function: SelectoverlayButton
        function SelectoverlayButtonPushed(app, event)
            % Load overlay figure.
            close all
            try
                % Allow user to select a file.
                [overlay_name, overlay_path] = uigetfile({'*.gif;*.jpg;*.png;*.tif;*.tiff', 'Figures (*.gif,*.jpg,*.png,*.tif,*.tiff)'; '*.*', 'All Files (*.*)'}, "Overlay image");
                drawnow; pause(0.1);
                figure(app.ImageOverlayerUIFigure);

                if ~isequal(overlay_name, 0) && ~isequal(overlay_path, 0)
                    % A file was selected.
                    cd(overlay_path);

                    % Load image.
                    app.overlay = imread(string(overlay_path) + string(overlay_name));
                    app.Overlay.ImageSource = app.overlay;

                    % Set lamp to red (new figure).
                    app.Lamp.Color = [1, 0, 0];
                end
            catch
                figure(app.ImageOverlayerUIFigure)
                uialert(app.ImageOverlayerUIFigure, "That didn't work, please try again. Did you select a figure?", "Invalid file");
                drawnow; pause(0.1);
            end
        end

        % Button pushed function: StartnewButton
        function StartnewButtonPushed(app, event)
            close all
            try
                % Set lamp to red (new transformation).
                app.Lamp.Color = [1, 0, 0];
                
                % Open control point dialog.
                [app.mp, app.fp] = cpselect(app.overlay, app.base, 'Wait', true);
                figure(app.ImageOverlayerUIFigure);

                % Display progress dialog.
                dialog = uiprogressdlg(app.ImageOverlayerUIFigure, 'Title', "Working on it", 'Message', "Transforming image...");
                drawnow; pause(0.1);

                % Transform the overlay.
                transform(app)
                
                % Update progress dialog.
                dialog.Value = 0.5;
                dialog.Message = "Printing result...";
                drawnow; pause(0.1);

                % Plot result.
                plot_map(app);

                % Set lamp to green (transformation done).
                app.Lamp.Color = [0, 1, 0];

                % Close dialog.
                dialog.Value = 1;
                dialog.Message = "Done!";
                drawnow; pause(0.5);
                close(dialog);

                figure(app.ImageOverlayerUIFigure);
            catch
                close all
                figure(app.ImageOverlayerUIFigure)
                uialert(app.ImageOverlayerUIFigure, "Something went wrong. Make sure you have added sufficient control points for your chosen transformation (see fitgeotrans documentation, link in README).", "Unknown error");
                drawnow; pause(0.1);
            end
        end

        % Selection changed function: TypeButtonGroup
        function TypeButtonGroupSelectionChanged(app, event)
            close all
            % Check whether transformation is still up to date.
            if isequal(app.Lamp.Color, [0, 1, 0])
                % Set lamp to red (new plot).
                app.Lamp.Color = [1, 0, 0];

                % Replot maps.
                plot_map(app);

                % Set lamp to green (plot done).
                app.Lamp.Color = [0, 1, 0];

                figure(app.ImageOverlayerUIFigure);
            end
        end

        % Button pushed function: EditexistingButton
        function EditexistingButtonPushed(app, event)
            close all
            try
                % Check whether a set of previous control points exists.
                if size(app.mp, 1) > 0 || size(app.fp, 1) > 0
                    % A previous set exists, set lamp to red (new transformation).
                    app.Lamp.Color = [1, 0, 0];

                    % Open control point dialog.
                    [app.mp, app.fp] = cpselect(app.overlay, app.base, app.mp, app.fp, 'Wait', true);
                    figure(app.ImageOverlayerUIFigure)

                    % Display progress dialog.
                    dialog = uiprogressdlg(app.ImageOverlayerUIFigure, 'Title', "Working on it", 'Message', "Transforming image...");
                    drawnow; pause(0.1);

                    % Transform the overlay.
                    transform(app)

                    % Update progress dialog.
                    dialog.Value = 0.5;
                    dialog.Message = "Printing result...";
                    drawnow; pause(0.1);

                    % Plot result.
                    plot_map(app);

                    % Set lamp to green (transformation done).
                    app.Lamp.Color = [0, 1, 0];

                    % Close dialog.
                    dialog.Value = 1;
                    dialog.Message = "Done!";
                    drawnow; pause(0.5);
                    close(dialog);

                    figure(app.ImageOverlayerUIFigure);
                else
                    % No previous set exists, move to new transformation.
                    StartnewButtonPushed(app);
                end
            catch
                close all
                figure(app.ImageOverlayerUIFigure)
                uialert(app.ImageOverlayerUIFigure, "Something went wrong. Make sure you have added sufficient control points for your chosen transformation (see fitgeotrans documentation, link in README).", "Unknown error");
                drawnow; pause(0.1);
            end
        end

        % Button pushed function: SaveresultButton
        function SaveresultButtonPushed(app, event)
            close all
            try
                % Ask user to specify save location and name.
                [save_name, save_path] = uiputfile('*.png', "Save location");
                drawnow; pause(0.1);
                figure(app.ImageOverlayerUIFigure);

                if ~isequal(save_name, 0) && ~isequal(save_path, 0)
                    % A location was selected.
                    save_name = extractBefore(save_name, '.');
                    cd(save_path);

                    % Display progess dialog.
                    dialog = uiprogressdlg(app.ImageOverlayerUIFigure, 'Title', "Working on it", 'Message', "Saving image 1/4...");
                    drawnow; pause(0.1);

                    %% Save base.
                    imwrite(app.base, string(save_path) + string(save_name) + "_base.png");

                    % Update progess dialog.
                    dialog.Value = 0.25;
                    dialog.Message = "Saving image 2/4...";
                    drawnow; pause(0.1);

                    %% Save overlay.
                    imwrite(app.overlay_transformed, string(save_path) + string(save_name) + "_overlay.png", 'Transparency', [0, 0, 0]);
                    
                    % Update progess dialog.
                    dialog.Value = 0.5;
                    dialog.Message = "Saving image 3/4...";
                    drawnow; pause(0.1);

                    %% Save brightness scaling (color).
                    fig = figure('Visible', 'off');
                    imwrite(imshowpair(app.base, app.overlay_transformed).CData, string(save_path) + string(save_name) + "_color.png");
                    
                    % Update progress dialog.
                    dialog.Value = 0.75;
                    dialog.Message = "Saving image 4/4...";
                    drawnow; pause(0.1);
                    
                    %% Save base and overlay (both).
                    ax = axes('Parent', fig);

                    % Plot base.
                    imshow(app.base, 'Parent', ax);
                    hold(ax, 'on');

                    % Mask transformed overlay to make black edges transparent.
                    mask = double(any(app.overlay_transformed, 3));

                    % Plot transformed overlay with masking.
                    image(app.overlay_transformed, 'AlphaData', mask, 'Parent', ax);

                    % Plot fixed control points.
                    plot(ax, app.fp(:, 1), app.fp(:, 2), 'b+', 'MarkerSize', 14, 'LineWidth', 2);

                    if app.plot_moving
                        % Plot control point differences.
                        for cp = 1:size(app.fp, 1)
                            plot(ax, [app.fp(cp, 1), app.mp_transformed(cp, 1)], [app.fp(cp, 2), app.mp_transformed(cp, 2)], 'g-', 'LineWidth', 2)
                        end

                        % Plot moving control points.
                        plot(ax, app.mp_transformed(:, 1), app.mp_transformed(:, 2), 'r+', 'MarkerSize', 14, 'LineWidth', 2);
                    end

                    % Save result.
                    imwrite(getframe(ax).cdata, string(save_path) + string(save_name) + "_combined.png");

                    hold(ax, 'off');
                    close(fig)

                    % Close dialog.
                    dialog.Value = 1;
                    dialog.Message = "Done!";
                    drawnow; pause(0.5);
                    close(dialog);

                    figure(app.ImageOverlayerUIFigure);
                end
            catch
                close all
                figure(app.ImageOverlayerUIFigure)
                uialert(app.ImageOverlayerUIFigure, "Something went wrong, please try again. Did you transform before saving?", "Unknown error");
                drawnow; pause(0.1);
            end
        end

        % Button pushed function: LoadpointsButton
        function LoadpointsButtonPushed(app, event)
            close all
            try
                % Ask user to specify control point location.
                [cp_name, cp_path] = uigetfile('*.mat', "Load control points");
                drawnow; pause(0.1);
                figure(app.ImageOverlayerUIFigure);

                if ~isequal(cp_name, 0) && ~isequal(cp_path, 0)
                    % A file was selected.
                    cd(cp_path);

                    % Extract control points.
                    load(string(cp_path) + string(cp_name), 'fp_save', 'mp_save');
                    app.fp = fp_save;
                    app.mp = mp_save;

                    % Set lamp to red (new transformation).
                    app.Lamp.Color = [1, 0, 0];

                    % Display progress dialog.
                    dialog = uiprogressdlg(app.ImageOverlayerUIFigure, 'Title', "Working on it", 'Message', "Transforming image...");
                    drawnow; pause(0.1);

                    % Transform the overlay.
                    transform(app)

                    % Update progress dialog.
                    dialog.Value = 0.5;
                    dialog.Message = "Printing result...";
                    drawnow; pause(0.1);

                    % Plot result.
                    plot_map(app);

                    % Set lamp to green (transformation done).
                    app.Lamp.Color = [0, 1, 0];

                    % Close dialog.
                    dialog.Value = 1;
                    dialog.Message = "Done!";
                    drawnow; pause(0.5);
                    close(dialog);

                    figure(app.ImageOverlayerUIFigure);
                end
            catch
                close all
                figure(app.ImageOverlayerUIFigure)
                uialert(app.ImageOverlayerUIFigure, "That didn't work, please try again. Did you select a .mat file created by saving points in this app?", "Invalid file");
                drawnow; pause(0.1);
            end
        end

        % Button pushed function: SavepointsButton
        function SavepointsButtonPushed(app, event)
            close all
            try
                % Check whether there are any control points to save.
                if size(app.mp, 1) > 0 || size(app.fp, 1) > 0
                    proceed = true;
                    if ~isequal(app.Lamp.Color, [0, 1, 0])
                        % The transformation was not completed yet.
                        figure(app.ImageOverlayerUIFigure)
                        selection = uiconfirm(app.ImageOverlayerUIFigure, "No transformation was successfully completed yet for this combination of images and control points, so the control points might not correspond to these images. Would you still like to save them?", "Confirm save", 'Options', {'Yes', 'Cancel'}, 'Icon', 'warning');
                        drawnow; pause(0.1);

                        if isequal(selection, 'Cancel')
                            proceed = false;
                        end
                    end

                    if proceed
                        % Ask user to specify a name and location for 
                        % the control points.
                        [cp_name, cp_path] = uiputfile('*.mat', "Save control points");
                        drawnow; pause(0.1);
                        figure(app.ImageOverlayerUIFigure);

                        if ~isequal(cp_name, 0) && ~isequal(cp_path, 0)
                            % A location was selected.
                            cd(cp_path);

                            % Save control points.
                            fp_save = app.fp;
                            mp_save = app.mp;
                            save(string(cp_path) + string(cp_name), 'fp_save', 'mp_save')
                        end
                    end
                else
                    % No points were selected.
                    figure(app.ImageOverlayerUIFigure)
                    uialert(app.ImageOverlayerUIFigure, "No control points selected yet, so there is nothing to save. To start a new transformation, press the start new button.", "No points selected", 'Icon', 'warning')
                end
            catch
                figure(app.ImageOverlayerUIFigure)
                uialert(app.ImageOverlayerUIFigure, "That didn't work, please try again.", "Unknown error");
                drawnow; pause(0.1);
            end
        end

        % Value changed function: TransformationDropDown
        function TransformationDropDownValueChanged(app, event)
            close all

            switch app.TransformationDropDown.Value
                case {'nonreflectivesimilarity', 'similarity', 'affine', 'projective'}
                    app.plot_moving = true;
                    app.OrderDropDownLabel.Visible = false;
                    app.OrderDropDown.Visible = false;
                    app.nEditFieldLabel.Visible = false;
                    app.nEditField.Visible = false;
                case 'polynomial'
                    app.plot_moving = false;
                    app.OrderDropDownLabel.Visible = true;
                    app.OrderDropDown.Visible = true;
                    app.nEditFieldLabel.Visible = false;
                    app.nEditField.Visible = false;
                case 'pwl'
                    app.plot_moving = false;
                    app.OrderDropDownLabel.Visible = false;
                    app.OrderDropDown.Visible = false;
                    app.nEditFieldLabel.Visible = false;
                    app.nEditField.Visible = false;
                case 'lwm'
                    app.plot_moving = false;
                    app.OrderDropDownLabel.Visible = false;
                    app.OrderDropDown.Visible = false;
                    app.nEditFieldLabel.Visible = true;
                    app.nEditField.Visible = true;
            end

            try
                % Set lamp to red (new transformation).
                app.Lamp.Color = [1, 0, 0];

                % Display progress dialog.
                dialog = uiprogressdlg(app.ImageOverlayerUIFigure, 'Title', "Working on it", 'Message', "Transforming image...");
                drawnow; pause(0.1);

                % Retransform the overlay.
                transform(app)
                
                % Update progress dialog.
                dialog.Value = 0.5;
                dialog.Message = "Printing result...";
                drawnow; pause(0.1);

                % Plot result.
                plot_map(app);

                % Set lamp to green (transformation done).
                app.Lamp.Color = [0, 1, 0];

                % Close dialog.
                dialog.Value = 1;
                dialog.Message = "Done!";
                drawnow; pause(0.5);
                close(dialog);

                figure(app.ImageOverlayerUIFigure);
            catch e
                disp(e)
                close all
                figure(app.ImageOverlayerUIFigure)
                uialert(app.ImageOverlayerUIFigure, "Something went wrong. Make sure you have added sufficient control points for your chosen transformation (see fitgeotrans documentation, link in README).", "Unknown error");
                drawnow; pause(0.1);
            end
        end

        % Value changed function: OrderDropDown
        function OrderDropDownValueChanged(app, event)
            close all
            
            try
                % Set lamp to red (new transformation).
                app.Lamp.Color = [1, 0, 0];

                % Display progress dialog.
                dialog = uiprogressdlg(app.ImageOverlayerUIFigure, 'Title', "Working on it", 'Message', "Transforming image...");
                drawnow; pause(0.1);

                % Retransform the overlay.
                transform(app)
                
                % Update progress dialog.
                dialog.Value = 0.5;
                dialog.Message = "Printing result...";
                drawnow; pause(0.1);

                % Plot result.
                plot_map(app);

                % Set lamp to green (transformation done).
                app.Lamp.Color = [0, 1, 0];

                % Close dialog.
                dialog.Value = 1;
                dialog.Message = "Done!";
                drawnow; pause(0.5);
                close(dialog);

                figure(app.ImageOverlayerUIFigure);
            catch e
                disp(e)
                close all
                figure(app.ImageOverlayerUIFigure)
                uialert(app.ImageOverlayerUIFigure, "Something went wrong. Make sure you have added sufficient control points for your chosen transformation (see fitgeotrans documentation, link in README).", "Unknown error");
                drawnow; pause(0.1);
            end
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            % Retransform the overlay.
            OrderDropDownValueChanged(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageOverlayerUIFigure = uifigure('Visible', 'off');
            app.ImageOverlayerUIFigure.Name = 'Image Overlayer';
            app.Panel = uipanel(app.ImageOverlayerUIFigure);
            app.Result = uiimage(app.Panel);
            app.Base = uiimage(app.Panel);
            app.BaseLabel = uilabel(app.Panel);
            app.BaseLabel.Text = 'Base';
            app.SelectbaseButton = uibutton(app.Panel, 'push');
            app.SelectbaseButton.ButtonPushedFcn = createCallbackFcn(app, @SelectbaseButtonPushed, true);
            app.SelectbaseButton.Text = 'Select base';
            app.OverlayLabel = uilabel(app.Panel);
            app.OverlayLabel.Text = 'Overlay';
            app.Overlay = uiimage(app.Panel);
            app.SelectoverlayButton = uibutton(app.Panel, 'push');
            app.SelectoverlayButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoverlayButtonPushed, true);
            app.SelectoverlayButton.Text = 'Select overlay';
            app.StartnewButton = uibutton(app.Panel, 'push');
            app.StartnewButton.ButtonPushedFcn = createCallbackFcn(app, @StartnewButtonPushed, true);
            app.StartnewButton.Text = 'Start new';
            app.EditexistingButton = uibutton(app.Panel, 'push');
            app.EditexistingButton.ButtonPushedFcn = createCallbackFcn(app, @EditexistingButtonPushed, true);
            app.EditexistingButton.Text = 'Edit existing';
            app.SaveresultButton = uibutton(app.Panel, 'push');
            app.SaveresultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultButtonPushed, true);
            app.SaveresultButton.Text = 'Save result';
            app.Lamp = uilamp(app.Panel);
            app.CorrelationGaugeLabel = uilabel(app.Panel);
            app.CorrelationGaugeLabel.Text = 'Correlation';
            app.CorrelationGauge = uigauge(app.Panel, 'circular');
            app.CorrelationGauge.Limits = [0 1];
            app.TypeButtonGroup = uibuttongroup(app.Panel);
            app.TypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeButtonGroupSelectionChanged, true);
            app.TypeButtonGroup.Title = 'Type';
            app.OverlayButton = uiradiobutton(app.TypeButtonGroup);
            app.OverlayButton.Text = 'Overlay';
            app.BaseButton = uiradiobutton(app.TypeButtonGroup);
            app.BaseButton.Text = 'Base';
            app.BothButton = uiradiobutton(app.TypeButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.ColorButton = uiradiobutton(app.TypeButtonGroup);
            app.ColorButton.Text = 'Color';
            app.CorrelationLabel = uilabel(app.Panel);
            app.CorrelationLabel.Text = '0.000';
            app.LoadpointsButton = uibutton(app.Panel, 'push');
            app.LoadpointsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpointsButtonPushed, true);
            app.LoadpointsButton.Text = 'Load points';
            app.SavepointsButton = uibutton(app.Panel, 'push');
            app.SavepointsButton.ButtonPushedFcn = createCallbackFcn(app, @SavepointsButtonPushed, true);
            app.SavepointsButton.Text = 'Save points';
            app.TransformationLabel = uilabel(app.Panel);
            app.TransformationLabel.Text = 'Transformation';
            app.TransformationDropDown = uidropdown(app.Panel);
            app.TransformationDropDown.Items = {'nonreflectivesimilarity', 'similarity', 'affine', 'projective', 'polynomial', 'pwl', 'lwm'};
            app.TransformationDropDown.ValueChangedFcn = createCallbackFcn(app, @TransformationDropDownValueChanged, true);
            app.TransformationDropDown.Value = 'projective';
            app.OrderDropDownLabel = uilabel(app.Panel);
            app.OrderDropDownLabel.Visible = 'off';
            app.OrderDropDownLabel.Text = 'Order';
            app.OrderDropDown = uidropdown(app.Panel);
            app.OrderDropDown.Items = {'2', '3', '4'};
            app.OrderDropDown.ValueChangedFcn = createCallbackFcn(app, @OrderDropDownValueChanged, true);
            app.OrderDropDown.Visible = 'off';
            app.OrderDropDown.Value = '2';
            app.nEditFieldLabel = uilabel(app.Panel);
            app.nEditFieldLabel.Visible = 'off';
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.Panel, 'numeric');
            app.nEditField.Limits = [6 Inf];
            app.nEditField.RoundFractionalValues = 'on';
            app.nEditField.ValueDisplayFormat = '%.0f';
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.nEditField.Visible = 'off';
            app.nEditField.Value = 6;
            % Show the figure after all components are created
            app.ImageOverlayerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_overlayer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageOverlayerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageOverlayerUIFigure)
        end
    end
end