classdef image_overlayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageOverlayerUIFigure  matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        SavepointsButton        matlab.ui.control.Button
        LoadpointsButton        matlab.ui.control.Button
        CorrelationLabel        matlab.ui.control.Label
        TypeButtonGroup         matlab.ui.container.ButtonGroup
        ColorButton             matlab.ui.control.RadioButton
        BothButton              matlab.ui.control.RadioButton
        BaseButton              matlab.ui.control.RadioButton
        OverlayButton           matlab.ui.control.RadioButton
        CorrelationGauge        matlab.ui.control.Gauge
        CorrelationGaugeLabel   matlab.ui.control.Label
        Lamp                    matlab.ui.control.Lamp
        SaveresultButton        matlab.ui.control.Button
        EditexistingButton      matlab.ui.control.Button
        StartnewButton          matlab.ui.control.Button
        SelectoverlayButton     matlab.ui.control.Button
        Overlay                 matlab.ui.control.Image
        OverlayLabel            matlab.ui.control.Label
        SelectbaseButton        matlab.ui.control.Button
        BaseLabel               matlab.ui.control.Label
        Base                    matlab.ui.control.Image
        Result                  matlab.ui.control.Image
    end

    
    properties (Access = private)
        % Figures.
        base % Base figure.
        overlay % Overlay figure.
        overlay_transformed % Transformed overlay figure.

        % Control points.
        fp % Fixed (base) control points.
        mp % Moving (overlay) control points.
        mp_transformed % Transformed moving (overlay) control points.
    end
    
    methods (Access = private)
        
        function transform(app)
            % Infer geometric transformation.
            transformation = fitgeotrans(app.mp, app.fp, 'projective');

            % Transform overlay image.
            base_coord = imref2d(size(app.base));
            app.overlay_transformed = imwarp(app.overlay, transformation, 'OutputView', base_coord);

            % Transform coordinates
            app.mp_transformed = transformPointsForward(transformation, app.mp);

            % Update cosine similarity.
            correlation = corr2(app.fp, app.mp_transformed);
            app.CorrelationGauge.Value = correlation;
            app.CorrelationLabel.Text = sprintf('%.3f', round(correlation, 3));
        end

        function plot_map(app)
            % Plot result.
            switch app.TypeButtonGroup.SelectedObject
                case app.ColorButton
                    % Display using default brightness scaling.
                    fig = figure;
                    app.Result.ImageSource = imshowpair(app.base, app.overlay_transformed).CData;
                    close(fig);
                case app.BaseButton
                    % Display using base.
                    fig = figure;
                    app.Result.ImageSource = imshow(app.base).CData;
                    close(fig)
                case app.OverlayButton
                    % Display using overlay
                    fig = figure;
                    app.Result.ImageSource = imshow(app.overlay_transformed).CData;
                    close(fig)
                case app.BothButton
                    % Display using both base and overlay.
                    fig = figure;
                    ax = axes('Parent', fig);
                    imshow(app.base, 'Parent', ax);
                    hold(ax, 'on');

                    % Mask transformed overlay to make black edges transparent.
                    mask = double(any(app.overlay_transformed, 3));
                    image(app.overlay_transformed, 'AlphaData', mask, 'Parent', ax);

                    % Plot control point differences.
                    for cp = 1:size(app.fp, 1)
                        plot(ax, [app.fp(cp, 1), app.mp_transformed(cp, 1)], [app.fp(cp, 2), app.mp_transformed(cp, 2)], 'g-', 'LineWidth', 2)
                    end

                    % Plot control points.
                    plot(ax, app.fp(:, 1), app.fp(:, 2), 'b+', 'MarkerSize', 14, 'LineWidth', 2);
                    plot(ax, app.mp_transformed(:, 1), app.mp_transformed(:, 2), 'r+', 'MarkerSize', 14, 'LineWidth', 2);

                    hold(ax, 'off');
                    app.Result.ImageSource = getframe(ax).cdata;
                    close(fig);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectbaseButton
        function SelectbaseButtonPushed(app, event)
            % Load base figure.
            try
                [base_name, base_path] = uigetfile({'*.gif;*.jpg;*.png;*.tif;*.tiff', 'Figures (*.gif,*.jpg,*.png,*.tif,*.tiff)'; '*.*', 'All Files (*.*)'}, "Base image");
                drawnow; pause(0.1);
                cd(base_path);
                figure(app.ImageOverlayerUIFigure);
                app.base = imread(string(base_path) + string(base_name));
                app.Base.ImageSource = app.base;
                app.Lamp.Color = [1, 0, 0];
            catch
                alert_fig = uifigure;
                uialert(alert_fig, "That didn't work, please try again. Did you select a figure?", "Invalid file", 'CloseFcn', @(h, e) close(alert_fig));
                drawnow; pause(0.1);
            end
        end

        % Button pushed function: SelectoverlayButton
        function SelectoverlayButtonPushed(app, event)
            % Load overlay figure.
            try
                [overlay_name, overlay_path] = uigetfile({'*.gif;*.jpg;*.png;*.tif;*.tiff', 'Figures (*.gif,*.jpg,*.png,*.tif,*.tiff)'; '*.*', 'All Files (*.*)'}, "Overlay image");
                drawnow; pause(0.1);
                cd(overlay_path);
                figure(app.ImageOverlayerUIFigure);
                app.overlay = imread(string(overlay_path) + string(overlay_name));
                app.Overlay.ImageSource = app.overlay;
                app.Lamp.Color = [1, 0, 0];
            catch
                alert_fig = uifigure;
                uialert(alert_fig, "That didn't work, please try again. Did you select a figure?", "Invalid file", 'CloseFcn', @(h, e) close(alert_fig));
                drawnow; pause(0.1);
            end
        end

        % Button pushed function: StartnewButton
        function StartnewButtonPushed(app, event)
            try
                app.Lamp.Color = [1, 0, 0];
                
                % Open control point dialog.
                [app.mp, app.fp] = cpselect(app.overlay, app.base, 'Wait', true);

                dialog_fig = uifigure;
                set(dialog_fig, 'HandleVisibility', 'on');
                dialog = uiprogressdlg(dialog_fig, 'Title', "Working on it", 'Message', "Transforming image...");
                drawnow; pause(0.1);

                transform(app)

                dialog.Value = 0.5;
                dialog.Message = "Printing result...";
                drawnow; pause(0.1);

                plot_map(app);
                app.Lamp.Color = [0, 1, 0];

                close(dialog);
                close(dialog_fig);
                figure(app.ImageOverlayerUIFigure);
            catch
                close all
                alert_fig = uifigure;
                uialert(alert_fig, "Something went wrong. Try selecting more control points.", "Unknown error", 'CloseFcn', @(h, e) close(alert_fig));
                drawnow; pause(0.1);
            end
        end

        % Selection changed function: TypeButtonGroup
        function TypeButtonGroupSelectionChanged(app, event)
            if app.Lamp.Color == [0, 1, 0]
                app.Lamp.Color = [1, 0, 0];

                % Re-plot maps.
                plot_map(app);

                app.Lamp.Color = [0, 1, 0];
                figure(app.ImageOverlayerUIFigure);
            end
        end

        % Button pushed function: EditexistingButton
        function EditexistingButtonPushed(app, event)
            % Allow user to edit control point selection and re-plot.
            if app.Lamp.Color == [0, 1, 0]
                app.Lamp.Color = [1, 0, 0];

                % Open control point dialog.
                [app.mp, app.fp] = cpselect(app.overlay, app.base, app.mp, app.fp, 'Wait', true);

                dialog_fig = uifigure;
                set(dialog_fig, 'HandleVisibility', 'on');
                dialog = uiprogressdlg(dialog_fig, 'Title', "Working on it", 'Message', "Transforming image...");
                drawnow; pause(0.1);

                transform(app)

                dialog.Value = 0.5;
                dialog.Message = "Printing result...";
                drawnow; pause(0.1);

                plot_map(app);
                app.Lamp.Color = [0, 1, 0];

                close(dialog);
                close(dialog_fig);
                figure(app.ImageOverlayerUIFigure);
            else
                close all
                alert_fig = uifigure;
                uialert(alert_fig, "Please use the Transform button for an initial transformation.", "Initial transformation required", 'CloseFcn', @(h, e) close(alert_fig));
                drawnow; pause(0.1);
            end
        end

        % Button pushed function: SaveresultButton
        function SaveresultButtonPushed(app, event)
            try
                % Ask user to specify save location and name.
                [save_name, save_path] = uiputfile('*.png', "Save location");
                drawnow; pause(0.1);
                cd(save_path);
                figure(app.ImageOverlayerUIFigure);
                if ~isequal(save_name, 0) && ~isequal(save_path, 0)
                    dialog_fig = uifigure;
                    set(dialog_fig, 'HandleVisibility', 'on');
                    dialog = uiprogressdlg(dialog_fig, 'Title', "Working on it", 'Message', "Saving image 1/4...");
                    drawnow; pause(0.1);

                    % Save base.
                    imwrite(app.base, string(save_path) + string(save_name) + "_base.png");
                    dialog.Value = 0.25;
                    dialog.Message = "Saving image 2/4...";
                    drawnow; pause(0.1);

                    % Save overlay.
                    imwrite(app.overlay_transformed, string(save_path) + string(save_name) + "_overlay.png", 'Transparency', [0, 0, 0]);
                    dialog.Value = 0.5;
                    dialog.Message = "Saving image 3/4...";
                    drawnow; pause(0.1);

                    % Brightness scaling.
                    fig = figure;
                    imwrite(imshowpair(app.base, app.overlay_transformed).CData, string(save_path) + string(save_name) + "_color.png");
                    dialog.Value = 0.75;
                    dialog.Message = "Saving image 4/4...";
                    drawnow; pause(0.1);
                    
                    % Base and overlay.
                    ax = axes('Parent', fig);
                    imshow(app.base, 'Parent', ax);
                    hold(ax, 'on');

                    % Mask transformed overlay to make black edges transparent.
                    mask = double(any(app.overlay_transformed, 3));
                    image(app.overlay_transformed, 'AlphaData', mask, 'Parent', ax);

                    % Plot control point differences.
                    for cp = 1:size(app.fp, 1)
                        plot(ax, [app.fp(cp, 1), app.mp_transformed(cp, 1)], [app.fp(cp, 2), app.mp_transformed(cp, 2)], 'g-', 'LineWidth', 2)
                    end

                    % Plot control points.
                    plot(ax, app.fp(:, 1), app.fp(:, 2), 'b+', 'MarkerSize', 14, 'LineWidth', 2);
                    plot(ax, app.mp_transformed(:, 1), app.mp_transformed(:, 2), 'r+', 'MarkerSize', 14, 'LineWidth', 2);

                    hold(ax, 'off');
                    imwrite(getframe(ax).cdata, string(save_path) + string(save_name) + "_combined.png");
                    close(fig)
                    close(dialog);
                    close(dialog_fig);
                    figure(app.ImageOverlayerUIFigure);
                end
            catch
                close all
                alert_fig = uifigure;
                uialert(alert_fig, "Something went wrong, please try again. Did you transform before saving?", "Unknown error", 'CloseFcn', @(h, e) close(alert_fig));
                drawnow; pause(0.1);
            end
        end

        % Button pushed function: LoadpointsButton
        function LoadpointsButtonPushed(app, event)
            try
                % Ask user to specify control point location.
                [cp_name, cp_path] = uigetfile('*.mat', "Load control points");
                drawnow; pause(0.1);
                cd(cp_path);
                figure(app.ImageOverlayerUIFigure);

                % Extract control points.
                load(string(cp_path) + string(cp_name), 'fp_save', 'mp_save');
                app.fp = fp_save;
                app.mp = mp_save;

                app.Lamp.Color = [1, 0, 0];

                dialog_fig = uifigure;
                set(dialog_fig, 'HandleVisibility', 'on');
                dialog = uiprogressdlg(dialog_fig, 'Title', "Working on it", 'Message', "Transforming image...");
                drawnow; pause(0.1);

                transform(app)

                dialog.Value = 0.5;
                dialog.Message = "Printing result...";
                drawnow; pause(0.1);

                plot_map(app);
                app.Lamp.Color = [0, 1, 0];

                close(dialog);
                close(dialog_fig);
                figure(app.ImageOverlayerUIFigure);
            catch
                close all
                alert_fig = uifigure;
                uialert(alert_fig, "That didn't work, please try again. Did you select a .mat file created by saving points in this app?", "Invalid file", 'CloseFcn', @(h, e) close(alert_fig));
                drawnow; pause(0.1);
            end
        end

        % Button pushed function: SavepointsButton
        function SavepointsButtonPushed(app, event)
            try
                % Ask user to specify a name and location for the control
                % points.
                [cp_name, cp_path] = uiputfile('*.mat', "Save control points");
                drawnow; pause(0.1);
                cd(cp_path);
                figure(app.ImageOverlayerUIFigure);
                
                % Save control points.
                fp_save = app.fp;
                mp_save = app.mp;
                save(string(cp_path) + string(cp_name), 'fp_save', 'mp_save')
            catch
                alert_fig = uifigure;
                uialert(alert_fig, "That didn't work, please try again.", "Unknown error", 'CloseFcn', @(h, e) close(alert_fig));
                drawnow; pause(0.1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageOverlayerUIFigure = uifigure('Visible', 'off');
            app.ImageOverlayerUIFigure.Name = 'Image Overlayer';
            app.Panel = uipanel(app.ImageOverlayerUIFigure);
            app.Result = uiimage(app.Panel);
            app.Base = uiimage(app.Panel);
            app.BaseLabel = uilabel(app.Panel);
            app.BaseLabel.Text = 'Base';
            app.SelectbaseButton = uibutton(app.Panel, 'push');
            app.SelectbaseButton.ButtonPushedFcn = createCallbackFcn(app, @SelectbaseButtonPushed, true);
            app.SelectbaseButton.Text = 'Select base';
            app.OverlayLabel = uilabel(app.Panel);
            app.OverlayLabel.Text = 'Overlay';
            app.Overlay = uiimage(app.Panel);
            app.SelectoverlayButton = uibutton(app.Panel, 'push');
            app.SelectoverlayButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoverlayButtonPushed, true);
            app.SelectoverlayButton.Text = 'Select overlay';
            app.StartnewButton = uibutton(app.Panel, 'push');
            app.StartnewButton.ButtonPushedFcn = createCallbackFcn(app, @StartnewButtonPushed, true);
            app.StartnewButton.Text = 'Start new';
            app.EditexistingButton = uibutton(app.Panel, 'push');
            app.EditexistingButton.ButtonPushedFcn = createCallbackFcn(app, @EditexistingButtonPushed, true);
            app.EditexistingButton.Text = 'Edit existing';
            app.SaveresultButton = uibutton(app.Panel, 'push');
            app.SaveresultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultButtonPushed, true);
            app.SaveresultButton.Text = 'Save result';
            app.Lamp = uilamp(app.Panel);
            app.CorrelationGaugeLabel = uilabel(app.Panel);
            app.CorrelationGaugeLabel.Text = 'Correlation';
            app.CorrelationGauge = uigauge(app.Panel, 'circular');
            app.CorrelationGauge.Limits = [0 1];
            app.TypeButtonGroup = uibuttongroup(app.Panel);
            app.TypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeButtonGroupSelectionChanged, true);
            app.TypeButtonGroup.Title = 'Type';
            app.OverlayButton = uiradiobutton(app.TypeButtonGroup);
            app.OverlayButton.Text = 'Overlay';
            app.BaseButton = uiradiobutton(app.TypeButtonGroup);
            app.BaseButton.Text = 'Base';
            app.BothButton = uiradiobutton(app.TypeButtonGroup);
            app.BothButton.Text = 'Both';
            app.ColorButton = uiradiobutton(app.TypeButtonGroup);
            app.ColorButton.Text = 'Color';
            app.ColorButton.Value = true;
            app.CorrelationLabel = uilabel(app.Panel);
            app.CorrelationLabel.Text = '0.000';
            app.LoadpointsButton = uibutton(app.Panel, 'push');
            app.LoadpointsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpointsButtonPushed, true);
            app.LoadpointsButton.Text = 'Load points';
            app.SavepointsButton = uibutton(app.Panel, 'push');
            app.SavepointsButton.ButtonPushedFcn = createCallbackFcn(app, @SavepointsButtonPushed, true);
            app.SavepointsButton.Text = 'Save points';
            % Show the figure after all components are created
            app.ImageOverlayerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_overlayer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageOverlayerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageOverlayerUIFigure)
        end
    end
end